classdef Post_Processing_Draft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        UploadDataTab                   matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        DataSettingsPanel               matlab.ui.container.Panel
        TagFormatDropDownLabel          matlab.ui.control.Label
        TagFormatDropDown               matlab.ui.control.DropDown
        HeaderFormatDropDownLabel       matlab.ui.control.Label
        HeaderFormatDropDown            matlab.ui.control.DropDown
        DataFormatDropDownLabel         matlab.ui.control.Label
        DataFormatDropDown              matlab.ui.control.DropDown
        CommentStyleDropDownLabel       matlab.ui.control.Label
        CommentStyleDropDown            matlab.ui.control.DropDown
        TagLinesSpinnerLabel            matlab.ui.control.Label
        TagLinesSpinner                 matlab.ui.control.Spinner
        HeaderLinesSpinnerLabel         matlab.ui.control.Label
        HeaderLinesSpinner              matlab.ui.control.Spinner
        ClicktoUploadDataButton         matlab.ui.control.Button
        DelimiterEditFieldLabel         matlab.ui.control.Label
        DelimiterEditField              matlab.ui.control.EditField
        LegendPanel                     matlab.ui.container.Panel
        UITable_3                       matlab.ui.control.Table
        DataSetInformationPanel         matlab.ui.container.Panel
        TextArea_4                      matlab.ui.control.TextArea
        PerformElementWiseDataOperationPanel  matlab.ui.container.Panel
        EnterOperationEditFieldLabel    matlab.ui.control.Label
        EnterOperationEditField         matlab.ui.control.EditField
        PerformButton                   matlab.ui.control.Button
        SelectChannelDropDownLabel      matlab.ui.control.Label
        SelectChannelDropDown           matlab.ui.control.DropDown
        FilterDataTab                   matlab.ui.container.Tab
        FilterAxes                      matlab.ui.control.UIAxes
        DataFilterPanel                 matlab.ui.container.Panel
        DataFilterListBox               matlab.ui.control.ListBox
        SelectDatatoFilterLabel         matlab.ui.control.Label
        EnterNumericTimeChannelEditField_2Label  matlab.ui.control.Label
        TimeFilterEditField             matlab.ui.control.NumericEditField
        PlotFFTButton_Filter            matlab.ui.control.Button
        FilterDataTextArea              matlab.ui.control.TextArea
        FiltFreqPanel                   matlab.ui.container.Panel
        SpecifyPassbandFrequencyLabel   matlab.ui.control.Label
        SpecifyPassbandFrequencyEditField  matlab.ui.control.NumericEditField
        FilteringOptionsButtonGroup     matlab.ui.container.ButtonGroup
        NoneButton_2                    matlab.ui.control.RadioButton
        LowPassButton_2                 matlab.ui.control.RadioButton
        HighPassButton_2                matlab.ui.control.RadioButton
        CheckBox11                      matlab.ui.control.CheckBox
        TimeHistoryStatsTab             matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UITable2                        matlab.ui.control.Table
        Panel_2                         matlab.ui.container.Panel
        SelectDatatoAnalyzeListBox      matlab.ui.control.ListBox
        CheckBox10                      matlab.ui.control.CheckBox
        SelectDatatoAnalyzeLabel        matlab.ui.control.Label
        EnterNumericTimeChannelEditFieldLabel  matlab.ui.control.Label
        EnterNumericTimeChannelEditField  matlab.ui.control.NumericEditField
        FiltFreqTable                   matlab.ui.control.Table
        FourierTransformTab             matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        TextArea_2                      matlab.ui.control.TextArea
        SelectIndependentVariableTimeLabel  matlab.ui.control.Label
        SelectIndependentVariableTimeListBox  matlab.ui.control.ListBox
        SelectDependentVariableListBoxLabel  matlab.ui.control.Label
        SelectDependentVariableListBox  matlab.ui.control.ListBox
        FourierTransformLabel           matlab.ui.control.Label
        OptionalSpecificationsPanel     matlab.ui.container.Panel
        InputSampleFrequencyCheckBox    matlab.ui.control.CheckBox
        DesiredSampleFrequencyEditField  matlab.ui.control.NumericEditField
        PlotFFTButton                   matlab.ui.control.Button
        WaveletTransformTab             matlab.ui.container.Tab
        WaveletTransformLabel           matlab.ui.control.Label
        WaveletPanel                    matlab.ui.container.Panel
        WaveletText                     matlab.ui.control.TextArea
        SelectIndependentVariableTimeListBox_3Label  matlab.ui.control.Label
        WaveletSelectIndependentVariableTimeListBox  matlab.ui.control.ListBox
        SelectDependentVariableListBox_3Label  matlab.ui.control.Label
        WaveletSelectDependentVariableListBox_3  matlab.ui.control.ListBox
        PlotWaveletTransformButton      matlab.ui.control.Button
        LaplaceTransformTab             matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        TextArea_5                      matlab.ui.control.TextArea
        SelectIndependentVariableTimeListBox_2Label  matlab.ui.control.Label
        SelectIndependentVariableTimeListBox_2  matlab.ui.control.ListBox
        SelectDependentVariableListBox_2Label  matlab.ui.control.Label
        SelectDependentVariableListBox_2  matlab.ui.control.ListBox
        PlotLaplaceTransformButton      matlab.ui.control.Button
        LaplaceTransformLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property =  % Description
    end
    
    methods (Access = private)
        
        function [data_obj] = read_data(app,directory,filename,datatype,varargin)
        if nargin < 3
            error('Not enough inputs')
        end
        default_channeltypes = [];
        default_tagtypes = [];
        default_tagformat = [];
        
        p = inputParser;
        valid_datatypes = @(x) mustBeMember(x,[0,1,2]);
        addRequired(p,'directory',@isstring);
        addRequired(p,'filename',@isstring);
        addRequired(p,'datatype',valid_datatypes);
        addOptional(p,'channeltypes',default_channeltypes)
        addOptional(p,'tagtypes',default_tagtypes);
        addOptional(p,'tagformat',default_tagformat,@isstring)
        
        parse(p,directory,filename,datatype,varargin{:});
        
        directory  = p.Results.directory;
        filename = p.Results.filename;
        datatype  = p.Results.datatype;
        channeltypes  = p.Results.channeltypes;
        tagtypes  = p.Results.tagtypes;
        tagformat = p.Results.tagformat;
        
        %%% Begin data reading process:
        
        file_location = strcat(directory,'\',filename);
        fileID = fopen(file_location,'r');
        
        if datatype == 0 % test data
            ntaglines = 2;
            nheaderlines = 1;
            data_tags = textscan(fileID,'%s',ntaglines,'CommentStyle','#');
            data_headers = textscan(fileID,'%s %s',nheaderlines,'Delimiter',',','CommentStyle','#');
            data = textscan(fileID,'%f %f','Delimiter',',','CommentStyle','#');
        elseif datatype == 1 % user-defined (dataClass)
            headerformat = repmat('%s',1,length(channeltypes)); % strings
            dataformat = repmat('%f',1,length(channeltypes));   % floats
            ntaglines = 1;
            nheaderlines = 1;
            data_tags = textscan(fileID,tagformat,ntaglines,'CommentStyle','#');
            data_headers = textscan(fileID,headerformat,nheaderlines,'Delimiter',',','CommentStyle','#');
            data = textscan(fileID,dataformat,'Delimiter',',','CommentStyle','#');
            % (Delimiter type and comment style should also probably be inputs!)
            
            data_obj = pkg.obj.dataClass();
            for tag = 1:length(data_tags)
                if iscell(data_tags{tag}) 
                    data_obj.addprop(tagtypes{tag}); % add property to object
                    data_obj.(tagtypes{tag}) = data_tags{tag}{1}; % populate
                elseif isnumeric(data_tags{tag})
                    data_obj.addprop(tagtypes{tag}); % add property to object
                    data_obj.(tagtypes{tag}) = data_tags{tag}(1); % populate
                end
            end
            propcnt = 1;
            for prop = 1:length(data_headers)
                if nnz(strcmp(channeltypes,channeltypes{prop})) > 1
                    while isprop(data_obj,(channeltypes{prop}))
                        if propcnt == 1
                            ch_name = channeltypes{prop};
                        end
                        channeltypes{prop} = strcat(ch_name,num2str(propcnt+1));
                        propcnt = propcnt + 1;
                    end
                    data_obj.addprop(channeltypes{prop}); 
                    data_obj.(channeltypes{prop}) = data{prop};
                    %%% Here I think it makes sense to use the containers.Map object to
                    %%% map header names to the channel name properties of data_obj. 
                    %%%Just haven't gotten around to doing it yet!
                    %%% https://www.mathworks.com/help/matlab/ref/containers.map.html#d120e731116
                end
            end
            fclose(fileID); 
        end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UploadDataButtonPushed(app, event)
            %first upload table, user will change to their txt file
            wavedata = readtable("zerodegdata.txt");
            app.UITable.Data = wavedata;
            app.UITable.ColumnName = wavedata.Properties.VariableNames;
            %next, apply the wave probe data columns to the graph
            %depending on the name of your given data, change the name
            %t = table2array(wavedata(:,"Time_Sec_"));
            %wpe = table2array(wavedata(:,"EastWaveProbe_m_"));
            %wpw = table2array(wavedata(:,"WestWaveProbe_m_"));
            %plot(app.UIAxes,t,wpe);
            %note: if the column name has a space, replace it with an
            %underscore when calling it
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            value = app.SelectSensorstoPlotAnalyzeListBox.Value;
            value.Multiselect = 'on';
        end

        % Callback function
        function SelectSensorstoPlotAnalyzeListBoxValueChanged2(app, event)
            %value = app.SelectSensorstoPlotAnalyzeListBox.Value;
            %this is extra, can't figure out how to delete it
        end

        % Callback function
        function PlotAnalyzeButtonPushed(app, event)
            wavedata = readtable("zerodegdata.txt");
            t = table2array(wavedata(:,"Time_Sec_"));
            %file will have to be changed here too
            if(strcmp(app.SelectSensorstoPlotAnalyzeListBox.Value,'East WaveProbe'))
                y = table2array(wavedata(:,"EastWaveProbe_m_"));
                East_Analysis = [mean(y), min(y),max(y),std(y)];
                app.UITable2.Data = East_Analysis;
                plot(app.UIAxes,t,y)
            else if(strcmp(app.SelectSensorstoPlotAnalyzeListBox.Value,'West WaveProbe'))
                a = table2array(wavedata(:,"EastWaveProbe_m_"));
                West_Analysis = [mean(a), min(a),max(a),std(a)];
                app.UITable2.Data = West_Analysis;
                plot(app.UIAxes,t,a)
            else if(strcmp(app.SelectSensorstoPlotAnalyzeListBox.Value,'A WaveProbe'))
                b = table2array(wavedata(:,"WaveProbe_A_m_"));
                A_Analysis = [mean(b), min(b),max(b),std(b)];
                app.UITable2.Data = A_Analysis;
                plot(app.UIAxes,t,b)
            else if(strcmp(app.SelectSensorstoPlotAnalyzeListBox.Value,'B WaveProbe'))
                c = table2array(wavedata(:,"WaveProbe_B_m_"));
                B_Analysis = [mean(c), min(c),max(c),std(c)];
                app.UITable2.Data = B_Analysis;
                plot(app.UIAxes,t,c)
            else if(strcmp(app.SelectSensorstoPlotAnalyzeListBox.Value,'C WaveProbe'))
                d = table2array(wavedata(:,"WaveProbe_C_m_"));
                C_Analysis = [mean(d), min(d),max(d),std(d)];
                app.UITable2.Data = C_Analysis;
                plot(app.UIAxes,t,d)
            else if(strcmp(app.SelectSensorstoPlotAnalyzeListBox.Value,'StringPot1'))
                e = table2array(wavedata(:,"StringPot_1_m_"));
                Str1_Analysis = [mean(e), min(e),max(e), std(e)];
                app.UITable2.Data = Str1_Analysis;
                plot(app.UIAxes,t,e)
            else
                g = table2array(wavedata(:,"StringPot_2_m_"));
                Str2_Analysis = [mean(g), min(g),max(g),std(g)];
                app.UITable2.Data = Str2_Analysis;
                plot(app.UIAxes,t,g)
            end
            end
            end
            end
            end
            end
                    
        end

        % Button pushed function: PlotFFTButton
        function PlotFFTButtonPushed(app, event)
            
        end

        % Value changed function: TextArea_4
        function TextArea_4ValueChanged(app, event)
            value = app.TextArea_4.Value;
            
        end

        % Button pushed function: PlotLaplaceTransformButton
        function PlotLaplaceTransformButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.TextArea = uitextarea(app.WelcomeTab);
            app.TextArea.Value = {'Welcome! '; ''; 'Instructions: '; '- Click on the tabs to complete different analyses'; ''; 'Important Notes:'; '- The textfile in the "readtable" functions must be changed to your specific text or excel file. Make sure your file is saved in the same folder as this app so it can be found.'; ''; 'How to add a function:'; '- instructions here'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '- Jake and Devon '; '(maybe list contact for questions, which can be added to for each person who adds something)'};
            app.UploadDataTab = uitab(app.TabGroup);
            app.UploadDataTab.Title = 'Upload Data';
            app.UITable_2 = uitable(app.UploadDataTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'};
            app.UITable_2.RowName = {};
            app.DataSettingsPanel = uipanel(app.UploadDataTab);
            app.DataSettingsPanel.Title = 'Data Settings';
            app.TagFormatDropDownLabel = uilabel(app.DataSettingsPanel);
            app.TagFormatDropDownLabel.Text = 'Tag Format';
            app.TagFormatDropDown = uidropdown(app.DataSettingsPanel);
            app.TagFormatDropDown.Items = {'Default (String)', 'Float', 'Integer'};
            app.TagFormatDropDown.Value = 'Default (String)';
            app.HeaderFormatDropDownLabel = uilabel(app.DataSettingsPanel);
            app.HeaderFormatDropDownLabel.Text = 'Header Format';
            app.HeaderFormatDropDown = uidropdown(app.DataSettingsPanel);
            app.HeaderFormatDropDown.Items = {'Default (String)', 'Float', 'Integer'};
            app.HeaderFormatDropDown.Value = 'Default (String)';
            app.DataFormatDropDownLabel = uilabel(app.DataSettingsPanel);
            app.DataFormatDropDownLabel.Text = 'Data Format';
            app.DataFormatDropDown = uidropdown(app.DataSettingsPanel);
            app.DataFormatDropDown.Items = {'Default (Float)', 'String', 'Integer', 'Option 4'};
            app.DataFormatDropDown.Value = 'Default (Float)';
            app.CommentStyleDropDownLabel = uilabel(app.DataSettingsPanel);
            app.CommentStyleDropDownLabel.Text = 'Comment Style';
            app.CommentStyleDropDown = uidropdown(app.DataSettingsPanel);
            app.CommentStyleDropDown.Items = {'Default (%)', '#'};
            app.CommentStyleDropDown.Value = 'Default (%)';
            app.TagLinesSpinnerLabel = uilabel(app.DataSettingsPanel);
            app.TagLinesSpinnerLabel.Text = 'Tag Lines';
            app.TagLinesSpinner = uispinner(app.DataSettingsPanel);
            app.HeaderLinesSpinnerLabel = uilabel(app.DataSettingsPanel);
            app.HeaderLinesSpinnerLabel.Text = 'Header Lines';
            app.HeaderLinesSpinner = uispinner(app.DataSettingsPanel);
            app.ClicktoUploadDataButton = uibutton(app.DataSettingsPanel, 'push');
            app.ClicktoUploadDataButton.Text = {'Click to '; 'Upload Data'};
            app.DelimiterEditFieldLabel = uilabel(app.DataSettingsPanel);
            app.DelimiterEditFieldLabel.Text = 'Delimiter';
            app.DelimiterEditField = uieditfield(app.DataSettingsPanel, 'text');
            app.DelimiterEditField.Tooltip = {'Enter the delimiter style here. If left empty, the default value is a comma. Other common values include a space '' '' or a tab ''\t''.'};
            app.LegendPanel = uipanel(app.UploadDataTab);
            app.LegendPanel.Title = 'Legend';
            app.UITable_3 = uitable(app.LegendPanel);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'};
            app.UITable_3.RowName = {};
            app.DataSetInformationPanel = uipanel(app.UploadDataTab);
            app.DataSetInformationPanel.Title = 'Data Set Information';
            app.TextArea_4 = uitextarea(app.DataSetInformationPanel);
            app.TextArea_4.ValueChangedFcn = createCallbackFcn(app, @TextArea_4ValueChanged, true);
            app.PerformElementWiseDataOperationPanel = uipanel(app.UploadDataTab);
            app.PerformElementWiseDataOperationPanel.Title = 'Perform Element-Wise Data Operation';
            app.EnterOperationEditFieldLabel = uilabel(app.PerformElementWiseDataOperationPanel);
            app.EnterOperationEditFieldLabel.Text = 'Enter Operation';
            app.EnterOperationEditField = uieditfield(app.PerformElementWiseDataOperationPanel, 'text');
            app.PerformButton = uibutton(app.PerformElementWiseDataOperationPanel, 'push');
            app.PerformButton.Text = 'Perform';
            app.SelectChannelDropDownLabel = uilabel(app.PerformElementWiseDataOperationPanel);
            app.SelectChannelDropDownLabel.Text = 'Select Channel';
            app.SelectChannelDropDown = uidropdown(app.PerformElementWiseDataOperationPanel);
            app.FilterDataTab = uitab(app.TabGroup);
            app.FilterDataTab.Title = 'Filter Data';
            app.FilterAxes = uiaxes(app.FilterDataTab);
            title(app.FilterAxes, 'Time History')
            xlabel(app.FilterAxes, 'X')
            ylabel(app.FilterAxes, 'Y')
            app.DataFilterPanel = uipanel(app.FilterDataTab);
            app.DataFilterListBox = uilistbox(app.DataFilterPanel);
            app.DataFilterListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', ''};
            app.SelectDatatoFilterLabel = uilabel(app.DataFilterPanel);
            app.SelectDatatoFilterLabel.Text = 'Select Data to Filter';
            app.EnterNumericTimeChannelEditField_2Label = uilabel(app.DataFilterPanel);
            app.EnterNumericTimeChannelEditField_2Label.Text = {'Enter Numeric'; 'Time Channel'};
            app.TimeFilterEditField = uieditfield(app.DataFilterPanel, 'numeric');
            app.PlotFFTButton_Filter = uibutton(app.FilterDataTab, 'push');
            app.PlotFFTButton_Filter.Text = 'Plot FFT';
            app.FilterDataTextArea = uitextarea(app.FilterDataTab);
            app.FilterDataTextArea.Value = {'Use the Fourier Transform to determine the Passband Freqency and enter it into the box below to filter the data.'};
            app.FiltFreqPanel = uipanel(app.FilterDataTab);
            app.SpecifyPassbandFrequencyLabel = uilabel(app.FiltFreqPanel);
            app.SpecifyPassbandFrequencyLabel.Text = 'Specify Passband Frequency';
            app.SpecifyPassbandFrequencyEditField = uieditfield(app.FiltFreqPanel, 'numeric');
            app.FilteringOptionsButtonGroup = uibuttongroup(app.FiltFreqPanel);
            app.FilteringOptionsButtonGroup.Title = 'Filtering Options';
            app.NoneButton_2 = uiradiobutton(app.FilteringOptionsButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.LowPassButton_2 = uiradiobutton(app.FilteringOptionsButtonGroup);
            app.LowPassButton_2.Text = 'Low-Pass';
            app.HighPassButton_2 = uiradiobutton(app.FilteringOptionsButtonGroup);
            app.HighPassButton_2.Text = 'High-Pass';
            app.CheckBox11 = uicheckbox(app.FiltFreqPanel);
            app.CheckBox11.Text = 'Check Box11';
            app.TimeHistoryStatsTab = uitab(app.TabGroup);
            app.TimeHistoryStatsTab.Title = 'Time History/Stats';
            app.UIAxes = uiaxes(app.TimeHistoryStatsTab);
            title(app.UIAxes, 'Time History')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Wave Probe (m)')
            app.UITable2 = uitable(app.TimeHistoryStatsTab);
            app.UITable2.ColumnName = {'Ch'; 'Mean'; 'Min'; 'Max'; 'Std Dev'};
            app.UITable2.RowName = {};
            app.Panel_2 = uipanel(app.TimeHistoryStatsTab);
            app.SelectDatatoAnalyzeListBox = uilistbox(app.Panel_2);
            app.SelectDatatoAnalyzeListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', ''};
            app.CheckBox10 = uicheckbox(app.Panel_2);
            app.CheckBox10.Text = 'Check Box10';
            app.SelectDatatoAnalyzeLabel = uilabel(app.Panel_2);
            app.SelectDatatoAnalyzeLabel.Text = 'Select Data to Analyze';
            app.EnterNumericTimeChannelEditFieldLabel = uilabel(app.Panel_2);
            app.EnterNumericTimeChannelEditFieldLabel.Text = {'Enter Numeric'; 'Time Channel'};
            app.EnterNumericTimeChannelEditField = uieditfield(app.Panel_2, 'numeric');
            app.FiltFreqTable = uitable(app.TimeHistoryStatsTab);
            app.FiltFreqTable.ColumnName = {'Ch'; 'Passband Frequency'};
            app.FiltFreqTable.RowName = {};
            app.FourierTransformTab = uitab(app.TabGroup);
            app.FourierTransformTab.Title = 'Fourier Transform';
            app.Panel = uipanel(app.FourierTransformTab);
            app.TextArea_2 = uitextarea(app.Panel);
            app.SelectIndependentVariableTimeLabel = uilabel(app.Panel);
            app.SelectIndependentVariableTimeLabel.Text = {'Select Independent '; 'Variable (Time)'};
            app.SelectIndependentVariableTimeListBox = uilistbox(app.Panel);
            app.SelectDependentVariableListBoxLabel = uilabel(app.Panel);
            app.SelectDependentVariableListBoxLabel.Text = {'Select Dependent'; 'Variable'};
            app.SelectDependentVariableListBox = uilistbox(app.Panel);
            app.FourierTransformLabel = uilabel(app.Panel);
            app.FourierTransformLabel.Text = 'Fourier Transform';
            app.OptionalSpecificationsPanel = uipanel(app.Panel);
            app.OptionalSpecificationsPanel.Title = 'Optional Specifications';
            app.InputSampleFrequencyCheckBox = uicheckbox(app.OptionalSpecificationsPanel);
            app.InputSampleFrequencyCheckBox.Text = {'Input Sample'; 'Frequency?'};
            app.DesiredSampleFrequencyEditField = uieditfield(app.OptionalSpecificationsPanel, 'numeric');
            app.PlotFFTButton = uibutton(app.Panel, 'push');
            app.PlotFFTButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFFTButtonPushed, true);
            app.PlotFFTButton.Text = 'Plot FFT';
            app.WaveletTransformTab = uitab(app.TabGroup);
            app.WaveletTransformTab.Title = 'Wavelet Transform';
            app.WaveletTransformLabel = uilabel(app.WaveletTransformTab);
            app.WaveletTransformLabel.Text = 'Wavelet Transform';
            app.WaveletPanel = uipanel(app.WaveletTransformTab);
            app.WaveletText = uitextarea(app.WaveletPanel);
            app.SelectIndependentVariableTimeListBox_3Label = uilabel(app.WaveletPanel);
            app.SelectIndependentVariableTimeListBox_3Label.Text = {'Select Independent '; 'Variable (Time)'};
            app.WaveletSelectIndependentVariableTimeListBox = uilistbox(app.WaveletPanel);
            app.SelectDependentVariableListBox_3Label = uilabel(app.WaveletPanel);
            app.SelectDependentVariableListBox_3Label.Text = {'Select Dependent'; 'Variable'};
            app.WaveletSelectDependentVariableListBox_3 = uilistbox(app.WaveletPanel);
            app.PlotWaveletTransformButton = uibutton(app.WaveletTransformTab, 'push');
            app.PlotWaveletTransformButton.Text = 'Plot Wavelet Transform';
            app.LaplaceTransformTab = uitab(app.TabGroup);
            app.LaplaceTransformTab.Title = 'Laplace Transform';
            app.Panel_3 = uipanel(app.LaplaceTransformTab);
            app.TextArea_5 = uitextarea(app.Panel_3);
            app.SelectIndependentVariableTimeListBox_2Label = uilabel(app.Panel_3);
            app.SelectIndependentVariableTimeListBox_2Label.Text = {'Select Independent '; 'Variable (Time)'};
            app.SelectIndependentVariableTimeListBox_2 = uilistbox(app.Panel_3);
            app.SelectDependentVariableListBox_2Label = uilabel(app.Panel_3);
            app.SelectDependentVariableListBox_2Label.Text = {'Select Dependent'; 'Variable'};
            app.SelectDependentVariableListBox_2 = uilistbox(app.Panel_3);
            app.PlotLaplaceTransformButton = uibutton(app.LaplaceTransformTab, 'push');
            app.PlotLaplaceTransformButton.ButtonPushedFcn = createCallbackFcn(app, @PlotLaplaceTransformButtonPushed, true);
            app.PlotLaplaceTransformButton.Text = {'Plot Laplace'; 'Transform'};
            app.LaplaceTransformLabel = uilabel(app.LaplaceTransformTab);
            app.LaplaceTransformLabel.Text = 'Laplace Transform';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Post_Processing_Draft

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end